classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        TabGroup        matlab.ui.container.TabGroup
        Tab             matlab.ui.container.Tab
        Label           matlab.ui.control.Label
        UITable         matlab.ui.control.Table
        Button          matlab.ui.control.Button
        Button_2        matlab.ui.control.Button
        Button_3        matlab.ui.control.Button
        Button_4        matlab.ui.control.Button
        Lamp            matlab.ui.control.Lamp
        UIAxes          matlab.ui.control.UIAxes
        UIAxes2         matlab.ui.control.UIAxes
        UIAxes2_2       matlab.ui.control.UIAxes
        UIAxes2_3       matlab.ui.control.UIAxes
        Tab_2           matlab.ui.container.Tab
        TabGroup2       matlab.ui.container.TabGroup
        Tab_3           matlab.ui.container.Tab
        UIAxes4         matlab.ui.control.UIAxes
        UIAxes5         matlab.ui.control.UIAxes
        UIAxes6         matlab.ui.control.UIAxes
        UIAxes7         matlab.ui.control.UIAxes
        SimulinkTab     matlab.ui.container.Tab
        TabGroup3       matlab.ui.container.TabGroup
        Tab_5           matlab.ui.container.Tab
        SimulinkLabel   matlab.ui.control.Label
        SimulinkButton  matlab.ui.control.Button
        Button_5        matlab.ui.control.Button
        UIAxes8         matlab.ui.control.UIAxes
        Tab_6           matlab.ui.container.Tab
        UIAxes9         matlab.ui.control.UIAxes
        UIAxes9_2       matlab.ui.control.UIAxes
        UIAxes9_3       matlab.ui.control.UIAxes
        Tab_7           matlab.ui.container.Tab
        UIAxes9_4       matlab.ui.control.UIAxes
        UIAxes9_5       matlab.ui.control.UIAxes
        UIAxes9_6       matlab.ui.control.UIAxes
        Tab_8           matlab.ui.container.Tab
        UIAxes10        matlab.ui.control.UIAxes
        UIAxes10_2      matlab.ui.control.UIAxes
        UIAxes10_3      matlab.ui.control.UIAxes
        Tab_9           matlab.ui.container.Tab
        Knob            matlab.ui.control.Knob
        Knob_2          matlab.ui.control.Knob
        Knob_3          matlab.ui.control.Knob
        Label_2         matlab.ui.control.Label
        Label_3         matlab.ui.control.Label
        Label_4         matlab.ui.control.Label
        Button_6        matlab.ui.control.Button
        Button_7        matlab.ui.control.Button
        Label_5         matlab.ui.control.Label
        Label_6         matlab.ui.control.Label
        Label_7         matlab.ui.control.Label
        Label_8         matlab.ui.control.Label
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
                        
                        app.UITable.Data = [1 0 0 0;
                                            2 0 0 0;
                                            3 0 0 0;
                                            4 0 0 0;
                                            5 0 0 0;
                                            6 0 0 0]
%                         app.Knob.Value = 0;
%                         app.Knob_2.Value = 0;
%                         app.Knob_3.Value = 0;
            cd C:\Users\sulta\Desktop\Delta\NewDeltaModel\sm3
            set_param('AssemblyDeltaV4/Constant','Value','0');
            set_param('AssemblyDeltaV4/Constant1','Value','0');
            set_param('AssemblyDeltaV4/Constant2','Value','0');
                        
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)

            plot3(app.UIAxes,app.UITable.Data(1,2),app.UITable.Data(1,3),app.UITable.Data(1,4),'*r');
            xlabel(app.UIAxes,'X (мм)');
            ylabel(app.UIAxes,'Y (мм)');
            zlabel(app.UIAxes,'Z (мм)');
            grid(app.UIAxes, 'on');  
            hold(app.UIAxes, 'on');
            for i = 2:6
            plot3(app.UIAxes,app.UITable.Data(i,2),app.UITable.Data(i,3),app.UITable.Data(i,4),'*r');
            % Построение линий.
            plot3(app.UIAxes,[app.UITable.Data(i-1,2),app.UITable.Data(i,2)],[app.UITable.Data(i-1,3),app.UITable.Data(i,3)],[app.UITable.Data(i-1,4),app.UITable.Data(i,4)],'Color', 'blue');
            end    
            % График проекции на плоскость OXY.
            % Построение точек.
            plot(app.UIAxes2,app.UITable.Data(1,2),app.UITable.Data(1,3),'*r');
            hold(app.UIAxes2, 'on');
            for i = 2:6
            plot(app.UIAxes2,app.UITable.Data(i,2),app.UITable.Data(i,3),'*r');
            % Построение линий.
            plot(app.UIAxes2,[app.UITable.Data(i-1,2),app.UITable.Data(i,2)],[app.UITable.Data(i-1,3),app.UITable.Data(i,3)],'Color', 'blue');
            end
            xlabel(app.UIAxes2,'X (мм)');
            ylabel(app.UIAxes2,'Y (мм)');
            grid(app.UIAxes2, 'on'); 
            % График проекции на плоскость OXZ.
            % Построение точек.
            plot(app.UIAxes2_2,app.UITable.Data(1,2),app.UITable.Data(1,4),'*r');
            hold(app.UIAxes2_2, 'on');
            for i = 2:6
            plot(app.UIAxes2_2,app.UITable.Data(i,2),app.UITable.Data(i,4),'*r');
            % Построение линий.
            plot(app.UIAxes2_2,[app.UITable.Data(i-1,2),app.UITable.Data(i,2)],[app.UITable.Data(i-1,4),app.UITable.Data(i,4)],'Color', 'blue');
            end
            xlabel(app.UIAxes2_2,'X (мм)');
            zlabel(app.UIAxes2_2,'Z (мм)');
            grid(app.UIAxes2_2, 'on');
            % График проекции на плоскость OYZ.
            % Построение точек.
            plot(app.UIAxes2_3,app.UITable.Data(1,3),app.UITable.Data(1,4),'*r');
            hold(app.UIAxes2_3, 'on');
            for i = 2:6
            plot(app.UIAxes2_3,app.UITable.Data(i,3),app.UITable.Data(i,4),'*r');
            % Построение линий.
            plot(app.UIAxes2_3,[app.UITable.Data(i-1,3),app.UITable.Data(i,3)],[app.UITable.Data(i-1,4),app.UITable.Data(i,4)],'Color', 'blue');
            end
            ylabel(app.UIAxes2_3,'Y (мм)');
            zlabel(app.UIAxes2_3,'Z (мм)');
            grid(app.UIAxes2_3, 'on');
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            r = randi([1,5],1,1);
            switch r
                case 1
                    app.UITable.Data = [1 0 0 -228.24;
                                        2 25 25 -239.5;
                                        3 21 21 -280;
                                        4 -28.7 -28.7 -270;
                                        5 -10 -10 -229;
                                        6 0 0 -228.24 ]; 
                case 2
                    app.UITable.Data = [1 0 0 -228.24;
                                        2 0 0 -239.5;
                                        3 0 -21 -260;
                                        4 0 0 -270;
                                        5 30 50 -290;
                                        6 0 0 -250 ];
                case 3
                    app.UITable.Data = [1 0 0 -228.24;
                                        2 0 0 -250;
                                        3 50 0 -250;
                                        4 0 50 -250;
                                        5 -50 0 -250;
                                        6 0 -50 -250 ]; 
                case 4
                    app.UITable.Data = [1 0 0 -228.24;
                                        2 0 0 -250;
                                        3 0 -50 -265;
                                        4 0 0 -275;
                                        5 0 50 -265;
                                        6 0 -50 -250 ]; 
                case 5
                    app.UITable.Data = [1 0 0 -228.24;
                                        2 25 25 -239.5;
                                        3 21 21 -280;
                                        4 -28.7 -28.7 -270;
                                        5 -10 -10 -229;
                                        6 0 0 -228.24 ]; 
            end
      
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            cla(app.UIAxes);
            cla(app.UIAxes2);
            cla(app.UIAxes2_2);
            cla(app.UIAxes2_3);
            cla(app.UIAxes4);
            cla(app.UIAxes5);
            cla(app.UIAxes6);
            cla(app.UIAxes7);
            app.Lamp.Color = 'red';
            app.UITable.Data = [1 0 0 0;
                                2 0 0 0;
                                3 0 0 0;
                                4 0 0 0;
                                5 0 0 0;
                                6 0 0 0]

        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
%             R=0.0433*1000;  % радиус окружности основания дельта-робота
%             r=0.0255*1000; % радиус окружности выходного звена дельта-робота
%             L = 0.065*1000; % длина первого сочленения
%             l = 0.150*1000; % длина второго сочленения  

%     R = 88.00125522;
%     r = 49.99032015;
%     l = 256; 
%     L =  115;
            
            L = constants();
%           XYZ = Fkinem(0, 0, 0,R,r,l,L)
            x=app.UITable.Data(:,2);
            y=app.UITable.Data(:,3);
            z=app.UITable.Data(:,4);
            T=L.ModTime;
            N=L.NumbOfSplits;
            t=0:T/N:T;
            %Решение обратной задачи о положениях в каждой точке траектории
            for i=1:N+1
                Q(:,i)=OZK(x(i),y(i),z(i),L);
            end
            %Задаём начальные условия для численного решения
            %Для каждой обобщённой координаты ищем коэффициенты многочлена
            for j=1:3
                v=Q(j,:);
                p(j,:)=Koefficienti(v,T,N);
                s(j,:)=Odin_mnogohlen(v,T,N);
            end
    
            %Построения графика изменения первой обобщённой координаты
            for i=1:3
                j=1; %номер участка разбиения
                for t=0:T/N:T-T/N
                    t1=t:0.1:t+T/N-0.1;
                    %Задаём зависимость обобщённой координаты от времени
                    switch j
                        case 1
                            q(i,1+(j-1)*size(t1,2):j*size(t1,2))=polyval(fliplr(p(i,1:5)),t1)
                        case N
                            q(i,1+(j-1)*size(t1,2):j*size(t1,2))=polyval(fliplr(p(i,4*N-2:4*N+2)),t1)
                        otherwise
                            q(i,1+(j-1)*size(t1,2):j*size(t1,2))=polyval(fliplr(p(i,6+4*(j-2):9+4*(j-2))),t1)
                    end
                    j=j+1;
                end
                t=0:0.1:T-0.1
                s1=fliplr(s(i,:));
                y=polyval(s1,t);
                switch i
                    case 1
                        plot(app.UIAxes4,t,q(i,:),'c');
                        hold(app.UIAxes4,'on');
                        plot(app.UIAxes4,t,y,'m');
                        hold(app.UIAxes4,'on');
                        plot(app.UIAxes4,0:T/N:T,Q(i,:),'r*');
                        xlabel(app.UIAxes4,'t, s');
                        ylabel(app.UIAxes4,'degrees');
                        grid(app.UIAxes4,'on');
%                         title('Q1')
                    case 2
                        plot(app.UIAxes6,t,q(i,:),'c');
                        hold(app.UIAxes6,'on');
                        plot(app.UIAxes6,t,y,'m');
                        hold(app.UIAxes6,'on');
                        plot(app.UIAxes6,0:T/N:T,Q(i,:),'r*')
                        xlabel(app.UIAxes6,'t, s');
                        ylabel(app.UIAxes6,'degrees');
                        grid(app.UIAxes6,'on');
%                         title('Q2')
                    case 3
                        plot(app.UIAxes5,t,q(i,:),'c');
                        hold(app.UIAxes5,'on');
                        plot(app.UIAxes5,t,y,'m');
                        hold(app.UIAxes5,'on');
                        plot(app.UIAxes5,0:T/N:T,Q(i,:),'r*');
                        xlabel(app.UIAxes5,'t, s');
                        ylabel(app.UIAxes5,'degrees');
                        grid(app.UIAxes5,'on');
%                         title('Q3')
                end
             end
                t11=[0:0.1:T-0.1]'; 
                Q1=q(1,:)';
                Q2=q(2,:)';
                Q3=q(3,:)';
                signal_T1=[t11,Q1]
                signal_T2=[t11,Q2]
                signal_T3=[t11,Q3]
                assignin('base','q',q);
                assignin('base','signal_T1',signal_T1);
                assignin('base','signal_T2',signal_T2);
                assignin('base','signal_T3',signal_T3);
                app.Lamp.Color = 'green';
                for i=1:1:length(q(1,:))
                    XYZ(:,i)=Fk(q(1,i),q(2,i),q(3,i),L)
                end
                assignin('base','XYZ',XYZ);
                plot3(app.UIAxes7,XYZ(1,:),XYZ(2,:),XYZ(3,:));
                xlabel(app.UIAxes7,'OX');
                ylabel(app.UIAxes7,'OY');
                zlabel(app.UIAxes7,'OZ');
                grid(app.UIAxes7,'on');
                hold(app.UIAxes7,'on');
                view(app.UIAxes7,-30,45);
                for i = 2:6
                    plot3(app.UIAxes7,app.UITable.Data(i,2),app.UITable.Data(i,3),app.UITable.Data(i,4),'*r');
                end    
                
        end

        % Button pushed function: SimulinkButton
        function SimulinkButtonPushed(app, event)
            uiopen('C:\Users\sulta\Desktop\Delta\NewDeltaModel\sm3');
            cd C:\Users\sulta\Desktop\Delta\NewDeltaModel\sm3;
            app.Button_5.Enable = 'on';

%             if exist('base','signal_T1')
%                 app.Button_5.Enable = 'on';
%             end

        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)

            x_s = evalin('base','x_s');
            y_s = evalin('base','y_s');
            z_s = evalin('base','z_s');
            Tq1 = evalin('base','Tq1');
            Tq2 = evalin('base','Tq2');
            Tq3 = evalin('base','Tq3');
            vq1 = evalin('base','vq1');
            vq2 = evalin('base','vq2');
            vq3 = evalin('base','vq3');
            aq1 = evalin('base','aq1');
            aq2 = evalin('base','aq2');
            aq3 = evalin('base','aq3');
%             XYZ = evalin('base','XYZ');
%             t=[0:0.1:9.9];
%             assignin('base','t',t);
%             plot(app.UIAxes8,t,XYZ(1,:),'c');
%             hold(app.UIAxes8,'on');
%             plot(app.UIAxes8,t,XYZ(2,:),'b');
%             hold(app.UIAxes8,'on');
%             plot(app.UIAxes8,t,XYZ(3,:),'g');
%             hold(app.UIAxes8,'on');
%             
%           
%           
            plot(app.UIAxes8,x_s.Time,x_s.data,'c');
            hold(app.UIAxes8,'on');
            plot(app.UIAxes8,y_s.Time,y_s.data,'b');
            hold(app.UIAxes8,'on');
            plot(app.UIAxes8,z_s.Time,z_s.data,'g');
            legend(app.UIAxes8,'x_s','y_s','z_s')
            hold(app.UIAxes8,'on');
            xlabel(app.UIAxes8,'t, s');
            ylabel(app.UIAxes8,'координата мм');
            grid(app.UIAxes8,'on');
            
            
            plot(app.UIAxes9,Tq1.Time,Tq1.data,'black');
            xlabel(app.UIAxes9,'t, s');
            ylabel(app.UIAxes9,'Кр.м, N*m');
            grid(app.UIAxes9,'on');
            
            plot(app.UIAxes9_2,Tq2.Time,Tq2.data,'black');
            xlabel(app.UIAxes9_2,'t, s');
            ylabel(app.UIAxes9_2,'Кр.м, N*m');
            grid(app.UIAxes9_2,'on');
            
            plot(app.UIAxes9_3,Tq3.Time,Tq3.data,'black');
            xlabel(app.UIAxes9_3,'t, s');
            ylabel(app.UIAxes9_3,'Кр.м, N*m');
            grid(app.UIAxes9_3,'on');
            
            plot(app.UIAxes9_4,vq1.Time,vq1.Data,'black');
            xlabel(app.UIAxes9_4,'t, s');
            ylabel(app.UIAxes9_4,'Скорость v, м/c');
            grid(app.UIAxes9_4,'on');
            
            plot(app.UIAxes9_5,vq2.Time,vq2.data,'black');
            xlabel(app.UIAxes9_5,'t, s');
            ylabel(app.UIAxes9_5,'Скорость v, м/c');
            grid(app.UIAxes9_5,'on');
            
            plot(app.UIAxes9_6,vq3.Time,vq3.data,'black');
            xlabel(app.UIAxes9_6,'t, s');
            ylabel(app.UIAxes9_6,'Скорость v, м/c');
            grid(app.UIAxes9_6,'on');
            
            plot(app.UIAxes10,aq1.Time,aq1.data,'black');
            xlabel(app.UIAxes10,'t, s');
            ylabel(app.UIAxes10,'Ускорение a, м/c^2');
            grid(app.UIAxes10,'on');
            
            
            plot(app.UIAxes10_2,aq2.Time,aq2.data,'black');
            xlabel(app.UIAxes10_2,'t, s');
            ylabel(app.UIAxes10_2,'Ускорение a, м/c^2');
            grid(app.UIAxes10_2,'on');
            
             plot(app.UIAxes10_3,aq3.Time,aq3.data,'black');
            xlabel(app.UIAxes10_3,'t, s');
            ylabel(app.UIAxes10_3,'Ускорение a, м/c^2');
            grid(app.UIAxes10_3,'on');
        end

        % Value changed function: Knob
        function KnobValueChanged(app, event)
            value = app.Knob.Value;
            app.Label_2.Text = num2str(value);
            set_param('AssemblyDeltaV4/Constant','Value',num2str(app.Knob.Value));
        end

        % Value changed function: Knob_2
        function Knob_2ValueChanged(app, event)
            value = app.Knob_2.Value;
            app.Label_3.Text = num2str(value);
            set_param('AssemblyDeltaV4/Constant1','Value',num2str(app.Knob_2.Value));

        end

        % Value changed function: Knob_3
        function Knob_3ValueChanged(app, event)
            value = app.Knob_3.Value;
            app.Label_4.Text = num2str(value);
            set_param('AssemblyDeltaV4/Constant2','Value',num2str(app.Knob_3.Value));
        end

        % Button pushed function: Button_6
        function Button_6Pushed(app, event)
            set_param('AssemblyDeltaV4','SimulationCommand','Start')
        end

        % Button pushed function: Button_7
        function Button_7Pushed(app, event)
            set_param('AssemblyDeltaV4','SimulationCommand','Stop')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Ввод данных';
            app.Label = uilabel(app.Tab);
            app.Label.Text = 'Ввод координат';
            app.UITable = uitable(app.Tab);
            app.UITable.ColumnName = {'Координата'; 'X'; 'Y'; 'Z'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = [false true true true];
            app.Button = uibutton(app.Tab, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = 'Показать траекторию';
            app.Button_2 = uibutton(app.Tab, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = 'Автозаполнение';
            app.Button_3 = uibutton(app.Tab, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Icon = 'trash.png';
            app.Button_3.Text = '';
            app.Button_4 = uibutton(app.Tab, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = 'Рассчитать траекторию';
            app.Lamp = uilamp(app.Tab);
            app.UIAxes = uiaxes(app.Tab);
            title(app.UIAxes, '3D')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.UIAxes2 = uiaxes(app.Tab);
            title(app.UIAxes2, 'Проекция траектории на OXY')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2_2 = uiaxes(app.Tab);
            title(app.UIAxes2_2, 'Проекция траектории на OXZ')
            xlabel(app.UIAxes2_2, 'X')
            ylabel(app.UIAxes2_2, 'Y')
            zlabel(app.UIAxes2_2, 'Z')
            app.UIAxes2_3 = uiaxes(app.Tab);
            title(app.UIAxes2_3, 'Проекция траектории на OYZ')
            xlabel(app.UIAxes2_3, 'X')
            ylabel(app.UIAxes2_3, 'Y')
            zlabel(app.UIAxes2_3, 'Z')
            app.Tab_2 = uitab(app.TabGroup);
            app.Tab_2.Title = 'Решение траекторной задачи';
            app.TabGroup2 = uitabgroup(app.Tab_2);
            app.Tab_3 = uitab(app.TabGroup2);
            app.Tab_3.Title = 'Графики изменения обобщенных координат';
            app.UIAxes4 = uiaxes(app.Tab_3);
            title(app.UIAxes4, 'θ1')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            app.UIAxes5 = uiaxes(app.Tab_3);
            title(app.UIAxes5, 'θ3')
            xlabel(app.UIAxes5, 'X')
            ylabel(app.UIAxes5, 'Y')
            zlabel(app.UIAxes5, 'Z')
            app.UIAxes6 = uiaxes(app.Tab_3);
            title(app.UIAxes6, 'θ2')
            xlabel(app.UIAxes6, 'X')
            ylabel(app.UIAxes6, 'Y')
            zlabel(app.UIAxes6, 'Z')
            app.UIAxes7 = uiaxes(app.Tab_3);
            title(app.UIAxes7, '3D')
            xlabel(app.UIAxes7, 'X')
            ylabel(app.UIAxes7, 'Y')
            zlabel(app.UIAxes7, 'Z')
            app.SimulinkTab = uitab(app.TabGroup);
            app.SimulinkTab.Title = 'Моделирование в Simulink';
            app.TabGroup3 = uitabgroup(app.SimulinkTab);
            app.Tab_5 = uitab(app.TabGroup3);
            app.Tab_5.Title = 'Графики изменения координат выходного звена';
            app.SimulinkLabel = uilabel(app.Tab_5);
            app.SimulinkLabel.Text = {'Для получения графиков необходимо'; 'сначала открыть Simulink модель и нажать '; 'на кнопку моделирования. После успешного'; 'выполнения данной операции кнопка'; 'построить графики станет доступной.'; ''; ''};
            app.SimulinkButton = uibutton(app.Tab_5, 'push');
            app.SimulinkButton.ButtonPushedFcn = createCallbackFcn(app, @SimulinkButtonPushed, true);
            app.SimulinkButton.Text = 'Открыть модель в Simulink';
            app.Button_5 = uibutton(app.Tab_5, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Text = 'Построить графики';
            app.UIAxes8 = uiaxes(app.Tab_5);
            title(app.UIAxes8, 'Графики изменения координат выходного звена')
            xlabel(app.UIAxes8, 'X')
            ylabel(app.UIAxes8, 'Y')
            zlabel(app.UIAxes8, 'Z')
            app.Tab_6 = uitab(app.TabGroup3);
            app.Tab_6.Title = 'Графики крутящих моментов';
            app.UIAxes9 = uiaxes(app.Tab_6);
            title(app.UIAxes9, 'Крутящий момент на первом сочленении')
            xlabel(app.UIAxes9, 'X')
            ylabel(app.UIAxes9, 'Y')
            zlabel(app.UIAxes9, 'Z')
            app.UIAxes9_2 = uiaxes(app.Tab_6);
            title(app.UIAxes9_2, 'Крутящий момент на втором сочленении')
            xlabel(app.UIAxes9_2, 'X')
            ylabel(app.UIAxes9_2, 'Y')
            zlabel(app.UIAxes9_2, 'Z')
            app.UIAxes9_3 = uiaxes(app.Tab_6);
            title(app.UIAxes9_3, 'Крутящий момент на третьем сочленении')
            xlabel(app.UIAxes9_3, 'X')
            ylabel(app.UIAxes9_3, 'Y')
            zlabel(app.UIAxes9_3, 'Z')
            app.Tab_7 = uitab(app.TabGroup3);
            app.Tab_7.Title = 'Графики изменения скоростей';
            app.UIAxes9_4 = uiaxes(app.Tab_7);
            title(app.UIAxes9_4, 'Скороть первой О.К')
            xlabel(app.UIAxes9_4, 'X')
            ylabel(app.UIAxes9_4, 'Y')
            zlabel(app.UIAxes9_4, 'Z')
            app.UIAxes9_5 = uiaxes(app.Tab_7);
            title(app.UIAxes9_5, 'Скороть второй О.К')
            xlabel(app.UIAxes9_5, 'X')
            ylabel(app.UIAxes9_5, 'Y')
            zlabel(app.UIAxes9_5, 'Z')
            app.UIAxes9_6 = uiaxes(app.Tab_7);
            title(app.UIAxes9_6, 'Скороть третьей О.К')
            xlabel(app.UIAxes9_6, 'X')
            ylabel(app.UIAxes9_6, 'Y')
            zlabel(app.UIAxes9_6, 'Z')
            app.Tab_8 = uitab(app.TabGroup3);
            app.Tab_8.Title = 'Графики изменения ускорений';
            app.UIAxes10 = uiaxes(app.Tab_8);
            title(app.UIAxes10, 'Ускорение первой О.К')
            xlabel(app.UIAxes10, 'X')
            ylabel(app.UIAxes10, 'Y')
            zlabel(app.UIAxes10, 'Z')
            app.UIAxes10_2 = uiaxes(app.Tab_8);
            title(app.UIAxes10_2, 'Ускорение второй О.К')
            xlabel(app.UIAxes10_2, 'X')
            ylabel(app.UIAxes10_2, 'Y')
            zlabel(app.UIAxes10_2, 'Z')
            app.UIAxes10_3 = uiaxes(app.Tab_8);
            title(app.UIAxes10_3, 'Ускорение третьей О.К')
            xlabel(app.UIAxes10_3, 'X')
            ylabel(app.UIAxes10_3, 'Y')
            zlabel(app.UIAxes10_3, 'Z')
            app.Tab_9 = uitab(app.TabGroup);
            app.Tab_9.Title = 'Ручное управление';
            app.Knob = uiknob(app.Tab_9, 'continuous');
            app.Knob.Limits = [-50 180];
            app.Knob.ValueChangedFcn = createCallbackFcn(app, @KnobValueChanged, true);
            app.Knob_2 = uiknob(app.Tab_9, 'continuous');
            app.Knob_2.Limits = [-50 180];
            app.Knob_2.ValueChangedFcn = createCallbackFcn(app, @Knob_2ValueChanged, true);
            app.Knob_3 = uiknob(app.Tab_9, 'continuous');
            app.Knob_3.Limits = [-50 180];
            app.Knob_3.ValueChangedFcn = createCallbackFcn(app, @Knob_3ValueChanged, true);
            app.Label_2 = uilabel(app.Tab_9);
            app.Label_2.Text = '0';
            app.Label_3 = uilabel(app.Tab_9);
            app.Label_3.Text = '0';
            app.Label_4 = uilabel(app.Tab_9);
            app.Label_4.Text = '0';
            app.Button_6 = uibutton(app.Tab_9, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_6Pushed, true);
            app.Button_6.Text = 'Запуск моделирования';
            app.Button_7 = uibutton(app.Tab_9, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @Button_7Pushed, true);
            app.Button_7.Text = 'Остановка моделирования';
            app.Label_5 = uilabel(app.Tab_9);
            app.Label_5.Text = 'Ручное управление ';
            app.Label_6 = uilabel(app.Tab_9);
            app.Label_6.Text = 'θ1';
            app.Label_7 = uilabel(app.Tab_9);
            app.Label_7.Text = 'θ2';
            app.Label_8 = uilabel(app.Tab_9);
            app.Label_8.Text = 'θ3';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end